classdef test_APP_DESIGN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CourtcircuitButton            matlab.ui.control.Button
        BackFlowButton                matlab.ui.control.Button
        OpenCircuitButton             matlab.ui.control.Button
        FermerButton                  matlab.ui.control.Button
        DmarrageControleurButton      matlab.ui.control.Button
        AlimentationAuxiliaireButton  matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         % Description
         
        t = 0;


        DataAnalogueActuel = struct("Courant",0,"CourantOUT", ...
            0,"TensionEntree",0,"TensionSortie",0,"DutyCycleValue",0);
        DataAnalogueFinal = struct("Courant",0,"CourantOUT", ...
            0,"TensionEntree",0,"TensionSortie",0,"DutyCycleValue",0);
        DataCTRState = struct("OCPINState",0,"BackFlowState",0,"PinSubstate",0 ...
            ,"CCProtState",0,"IR2184State",0,"DutyCycleCTR",150);
        HandleAnimatedLINE = struct("Courant",0,"CourantSortie", ...
            0,"TensionEntree",0,"TensionSortie",0,"DutyCycle",0);
        temps;
    end
         
    properties (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FermerButton
        function FermerButtonPushed(app, event)
            

            [app.temps, app.DataAnalogueFinal.TensionEntree] = getpoints(app.HandleAnimatedLINE.TensionEntree);
                delete(app.UIFigure)


        end

        % Button pushed function: DmarrageControleurButton
        function DmarrageControleurButtonPushed(app, event)
        
            app.DataCTRState.PinSubsate = 0;
            app.DataCTRState.OCPINState = 0;
            app.DataCTRState.BackFlowState = 0;
            app.DataCTRState.CCProtState = 0;
            app.DataCTRState.IR2184State = 0;
            app.DataCTRState.DutyCyleCTR = 150;

        
        mqClient = mqttclient("mqtt://192.168.0.145",Port=1883)
        topicToSub = "ESP32/MPPT/TensionIN";
        %subscribe(mqClient, topicToSub,'QualityOfService',0)
        %subscribe(mqClient, "ESP32/MPPT/TensionOUT")

        %subscribe(mqClient, "ESP32/MPPT/CourantIN")
        %subscribe(mqClient, "ESP32/MPPT/CourantOUT")
        %subscribe(mqClient, "ESP32/MPPT/DutyCycleValue")
        subscribe(mqClient, "ESP32/MPPT/DATAJSON")

        startTime = datetime('now');

        app.HandleAnimatedLINE = INIAffichage(app);

        while (isvalid(app))

           t =  datetime('now') - startTime;
           [app.DataAnalogueActuel] = AcquisitionData(mqClient,app.DataAnalogueActuel);
           MAJAffichage(app.HandleAnimatedLINE,app.DataAnalogueActuel,t);
           SendCTRLData(mqClient,app.DataCTRState);
           pause(0.5);
   
        end


        end

        % Button pushed function: AlimentationAuxiliaireButton
        function AlimentationAuxiliaireButtonPushed(app, event)
            
            if(app.DataCTRState.PinSubstate == 0)
                app.DataCTRState.PinSubstate =1;

            elseif(app.DataCTRState.PinSubstate==1)
                app.DataCTRState.PinSubstate = 0;

            else
                app.DataCTRState.PinSubstate =0;
            end


        end

        % Button pushed function: OpenCircuitButton
        function OpenCircuitButtonPushed(app, event)
             if(app.DataCTRState.OCPINState == 0)
                app.DataCTRState.OCPINState =1;

            elseif(app.DataCTRState.OCPINState==1)
                app.DataCTRState.OCPINState = 0;

            else
                app.DataCTRState.OCPINState =0;
            end
        end

        % Button pushed function: BackFlowButton
        function BackFlowButtonPushed(app, event)
             if(app.DataCTRState.BackFlowState == 0)
                app.DataCTRState.BackFlowState =1;

            elseif(app.DataCTRState.BackFlowState==1)
                app.DataCTRState.BackFlowState = 0;

            else
                app.DataCTRState.BackFlowState =0;
            end
        end

        % Button pushed function: CourtcircuitButton
        function CourtcircuitButtonPushed(app, event)
            if(app.DataCTRState.CCProtState == 0)
                app.DataCTRState.CCProtState =1;

            elseif(app.DataCTRState.CCProtState==1)
                app.DataCTRState.CCProtState = 0;

            else
                app.DataCTRState.CCProtState =0;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {763, 763};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AlimentationAuxiliaireButton = uibutton(app.LeftPanel, 'push');
            app.AlimentationAuxiliaireButton.ButtonPushedFcn = createCallbackFcn(app, @AlimentationAuxiliaireButtonPushed, true);
            app.AlimentationAuxiliaireButton.Text = {'Alimentation '; 'Auxiliaire'};
            app.DmarrageControleurButton = uibutton(app.LeftPanel, 'push');
            app.DmarrageControleurButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrageControleurButtonPushed, true);
            app.DmarrageControleurButton.Text = 'Démarrage Controleur';
            app.FermerButton = uibutton(app.LeftPanel, 'push');
            app.FermerButton.ButtonPushedFcn = createCallbackFcn(app, @FermerButtonPushed, true);
            app.FermerButton.Text = 'Fermer';
            app.OpenCircuitButton = uibutton(app.LeftPanel, 'push');
            app.OpenCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCircuitButtonPushed, true);
            app.OpenCircuitButton.Text = 'Open Circuit';
            app.BackFlowButton = uibutton(app.LeftPanel, 'push');
            app.BackFlowButton.ButtonPushedFcn = createCallbackFcn(app, @BackFlowButtonPushed, true);
            app.BackFlowButton.Text = 'BackFlow';
            app.CourtcircuitButton = uibutton(app.LeftPanel, 'push');
            app.CourtcircuitButton.ButtonPushedFcn = createCallbackFcn(app, @CourtcircuitButtonPushed, true);
            app.CourtcircuitButton.Text = 'Court-circuit';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Tension et Courant d''entrée du système')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Tension et Courant de sortie du système')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Rapport cyclique du système')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Températeure')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_APP_DESIGN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end